classdef BLapp2020 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        TpSliderLabel               matlab.ui.control.Label
        TpSlider                    matlab.ui.control.Slider
        TfSliderLabel               matlab.ui.control.Label
        TfSlider                    matlab.ui.control.Slider
        TvSliderLabel               matlab.ui.control.Label
        TvSlider                    matlab.ui.control.Slider
        CirculatoryCheckBox         matlab.ui.control.CheckBox
        ImpulsiveCheckBox           matlab.ui.control.CheckBox
        PotentialCheckBox           matlab.ui.control.CheckBox
        AccVortexCheckBox           matlab.ui.control.CheckBox
        TotalCheckBox               matlab.ui.control.CheckBox
        ExperimentalCheckBox        matlab.ui.control.CheckBox
        ModKirchoffCheckBox         matlab.ui.control.CheckBox
        LaggedCheckBox              matlab.ui.control.CheckBox
        DatasetDropDownLabel        matlab.ui.control.Label
        DatasetDropDown             matlab.ui.control.DropDown
        ModelDropDownLabel          matlab.ui.control.Label
        ModelDropDown               matlab.ui.control.DropDown
        FilterexpdataSwitchLabel    matlab.ui.control.Label
        FilterSwitch                matlab.ui.control.Switch
        TfField                     matlab.ui.control.NumericEditField
        TvField                     matlab.ui.control.NumericEditField
        TvlSliderLabel              matlab.ui.control.Label
        TvlSlider                   matlab.ui.control.Slider
        TpField                     matlab.ui.control.NumericEditField
        TvlField                    matlab.ui.control.NumericEditField
        cDropDownLabel              matlab.ui.control.Label
        CaseDropDown                matlab.ui.control.DropDown
        StaticCheckBox              matlab.ui.control.CheckBox
        QuasiStaticCheckBox         matlab.ui.control.CheckBox
        ModKCNICheckBox             matlab.ui.control.CheckBox
        StaticstallangleLabel       matlab.ui.control.Label
        AlphaSSField                matlab.ui.control.NumericEditField
        EntangledCheckBox           matlab.ui.control.CheckBox
        RightPanel                  matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        LoadsAngleTab               matlab.ui.container.Tab
        UIAxesLoadsAngle            matlab.ui.control.UIAxes
        SaveFigureButtonLoadsAngle  matlab.ui.control.Button
        LoadsTimeTab                matlab.ui.container.Tab
        UIAxesLoadsTime             matlab.ui.control.UIAxes
        SaveFigureButtonLoadsTime   matlab.ui.control.Button
        SepAngleTab                 matlab.ui.container.Tab
        UIAxesSepAngle              matlab.ui.control.UIAxes
        SaveFigureButtonSepAngle    matlab.ui.control.Button
        SepTimeTab                  matlab.ui.container.Tab
        UIAxesSepTime               matlab.ui.control.UIAxes
        SaveFigureButtonSepTime     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % airfoil motion
        motion
        airfoil
        Tp = 5.48;
        Tf = 3;
        Tv = 1;
        Tvl = 1.7;
        params
        cmap = linspecer(12,'qualitative');
        CurveList = {'total','exp','steady','C_N^{qs}','C_N^{k}','C_N^C','C_N^I','C_N^p','C_N''','C_N^f','C_N^v'} % Labels for all plotted load curves
    end
    
    methods (Access = private)
        function updatePlots(app)
            switch app.ModelDropDown.Value
                case 'Beddoes-Leishman'
                    app.motion.BeddoesLeishman(app.airfoil,app.Tp,app.Tf,app.Tv,app.Tvl,'experimental');
                    if strcmp(app.DatasetDropDown.Value,'SH2019')
                        app.params = sprintf('r=%0.3f,T_p=%0.1f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.r,app.Tp,app.Tf,app.Tv,app.Tvl);
                    else
                        app.params = sprintf('r=%0.3f,T_p=%0.1f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.k,app.Tp,app.Tf,app.Tv,app.Tvl);
                    end
                case 'BL-Bangga'
                    app.motion.BLBangga(app.airfoil,app.Tp,app.Tf,app.Tv,app.Tvl,'experimental')
                    if strcmp(app.DatasetDropDown.Value,'SH2019')
                        app.params = sprintf('r=%0.3f,T_p=%0.1f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.r,app.Tp,app.Tf,app.Tv,app.Tvl);
                    else
                        app.params = sprintf('r=%0.3f,T_p=%0.1f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.k,app.Tp,app.Tf,app.Tv,app.Tvl);
                    end
                case 'BL-Sheng'
                    app.motion.BLSheng(app.airfoil,app.Tf,app.Tv,app.Tvl,'experimental');
                    load('linfit_flatplate.mat','Talpha');
                    if strcmp(app.DatasetDropDown.Value,'SH2019')
                        app.params = sprintf('r=%0.3f,T_\\alpha=%0.2f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.r,Talpha,app.Tf,app.Tv,app.Tvl);
                    else
                        app.params = sprintf('r=%0.3f,T_\\alpha=%0.2f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.k,Talpha,app.Tf,app.Tv,app.Tvl);
                    end
                case 'BL-Sheng with expfit'
                    app.motion.BLexpfit(app.airfoil,app.Tf,app.Tv,app.Tvl,'experimental');
                    [~,Talpha] = Expfit(app.airfoil,app.motion);
                    if strcmp(app.DatasetDropDown.Value,'SH2019')
                        app.params = sprintf('r=%0.3f,T_\\alpha=%0.2f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.r,Talpha,app.Tf,app.Tv,app.Tvl);
                    else
                        app.params = sprintf('r=%0.3f,T_\\alpha=%0.2f, T_f=%0.1f, T_v=%0.1f, T_{vl}=%0.1f',app.motion.k,Talpha,app.Tf,app.Tv,app.Tvl);
                    end
                    
                otherwise
                    error('The selected model is not a valid option.')
            end
            % plot only the graph corresponding to the active tab
            switch app.TabGroup.SelectedTab
                case app.LoadsAngleTab
                    app.plotLoadsAngle()
                case app.LoadsTimeTab
                    app.plotLoadsTime()
                case app.SepAngleTab
                    app.plotSepAngle()
                case app.SepTimeTab
                    app.plotSepTime()
            end
        end
        
        function plotLoadsAngle(app)
            set(app.UIAxesLoadsAngle,'NextPlot','replacechildren','ColorOrder',app.cmap)
            
            if strcmp(app.DatasetDropDown.Value,'simcos2008')
                t_end = 1/app.motion.freq;
            else
                t_end = Inf;
            end
            if app.ExperimentalCheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CN,1)
            end
            if app.TotalCheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CN_LB,2)
            end
            if app.StaticCheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CNsteady,3)
            end
            if app.QuasiStaticCheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CNqs,4)
            end
            if app.ModKCNICheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CNk,5)
            end
            if app.CirculatoryCheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CNC,6)
            end
            if app.ImpulsiveCheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CNI,7)
            end
            if app.PotentialCheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CNp,8)
            end
            if app.LaggedCheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CNprime,9);
            end
            if app.ModKirchoffCheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CNf,10)
            end
            if app.AccVortexCheckBox.Value
                app.plotLine(app.UIAxesLoadsAngle,app.motion.alpha,app.motion.CNv,11)
            end
            
            axis(app.UIAxesLoadsAngle,'auto')
            title(app.UIAxesLoadsAngle,app.params)
            if length(app.UIAxesLoadsAngle.Children)>1 % only displays legend if there is more than one curve plotted
                legend(app.UIAxesLoadsAngle,'Location','NorthWest',"FontSize",20)
            else
                legend(app.UIAxesLoadsAngle,'off')
            end
            set(app.UIAxesLoadsTime,'NextPlot','replace','ColorOrder',app.cmap)
        end
        
        function plotLoadsTime(app)
            set(app.UIAxesLoadsTime,'NextPlot','replacechildren','ColorOrder',app.cmap)
            
            if strcmp(app.DatasetDropDown.Value,'simcos2008')
                t_end = 1/app.motion.freq;
            else
                t_end = Inf;
            end
            
            n = find(app.motion.t<t_end,1,'last');
            if app.ExperimentalCheckBox.Value
                plot(app.UIAxesLoadsTime,app.motion.S(1:min([length(app.motion.CN),n])),app.motion.CN(app.motion.t<t_end),'SeriesIndex',1,"LineWidth",3,'DisplayName','exp')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            if app.TotalCheckBox.Value
                plot(app.UIAxesLoadsTime,app.motion.S(1:min([length(app.motion.CN_LB),n])),app.motion.CN_LB(1:min([length(app.motion.CN_LB),n])),'SeriesIndex',2,"LineWidth",3,'DisplayName','total')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            if app.StaticCheckBox.Value
                plot(app.UIAxesLoadsTime,app.motion.S(1:min([length(app.motion.CNsteady),n])),app.motion.CNsteady(1:min([length(app.motion.CNsteady),n])),'SeriesIndex',3,"LineWidth",2,'DisplayName','C_N^{static}')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            if app.QuasiStaticCheckBox.Value
                plot(app.UIAxesLoadsTime,app.motion.S(1:min([length(app.motion.CNqs),n])),app.motion.CNqs(1:min([length(app.motion.CNqs),n])),'SeriesIndex',4,"LineWidth",2,'DisplayName','C_N^{qs}')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            if app.ModKCNICheckBox.Value        
                m = min([n,length(app.motion.CNf)]);
                plot(app.UIAxesLoadsTime,app.motion.S(1:m),app.motion.CNf(1:m),'SeriesIndex',5,"LineWidth",2,'DisplayName','C_N^{f}')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            if app.CirculatoryCheckBox.Value
                plot(app.UIAxesLoadsTime,app.motion.S(1:min([length(app.motion.CNC),n])),app.motion.CNC(1:min([length(app.motion.CNC),n])),'SeriesIndex',6,"LineWidth",2,'DisplayName','C_N^C')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            
            if app.ImpulsiveCheckBox.Value
                plot(app.UIAxesLoadsTime,app.motion.S(1:min([length(app.motion.CNI),n])),app.motion.CNI(1:min([length(app.motion.CNI),n])),'SeriesIndex',7,"LineWidth",2,'DisplayName','C_N^I')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            
            if app.PotentialCheckBox.Value
                plot(app.UIAxesLoadsTime,app.motion.S(1:min([length(app.motion.CNp),n])),app.motion.CNp(1:min([length(app.motion.CNp),n])),'SeriesIndex',8,"LineWidth",2,'DisplayName','C_N^p')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            
            if app.LaggedCheckBox.Value
                plot(app.UIAxesLoadsTime,app.motion.S(1:min([length(app.motion.CNprime),n])),app.motion.CNprime(1:min([length(app.motion.CNprime),n])),'SeriesIndex',9,"LineWidth",2,'DisplayName','C_N''')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            
            if app.ModKirchoffCheckBox.Value
                m = min([n,length(app.motion.CNk)]);
                plot(app.UIAxesLoadsTime,app.motion.S(1:m),app.motion.CNk(1:m),'SeriesIndex',10,"LineWidth",2,'DisplayName','C_N^{k}')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            
            if app.AccVortexCheckBox.Value
                plot(app.UIAxesLoadsTime,app.motion.S(1:min([length(app.motion.CNv),n])),app.motion.CNv(1:min([length(app.motion.CNv),n])),'SeriesIndex',11,"LineWidth",2,'DisplayName','C_N^v')
                set(app.UIAxesLoadsTime,'NextPlot','add', 'ColorOrder',app.cmap);
            end
            
            axis(app.UIAxesLoadsTime,"auto x")
            axis(app.UIAxesLoadsTime,[0 Inf -0.1 3])
            title(app.UIAxesLoadsTime,app.params)
            if length(app.UIAxesLoadsTime.Children)>1  % only displays legend if there is more than one curve plotted
                legend(app.UIAxesLoadsTime,'Location','NorthWest',"FontSize",20)
            else
                legend(app.UIAxesLoadsTime,'off')
            end
            
        end
        
        function plotSepAngle(app)
            % plot seppoint in alpha
            hold(app.UIAxesSepAngle,'off')
            plot(app.UIAxesSepAngle,app.airfoil.steady.alpha,app.airfoil.steady.fexp,"LineWidth",2,'DisplayName','f_{exp}')
            hold(app.UIAxesSepAngle,'on')
            if ~strcmp(app.ModelDropDown.Value,'BL-Bangga')
                plot(app.UIAxesSepAngle,app.airfoil.steady.alpha,app.motion.f,"LineWidth",2,'DisplayName','f')
            end
            plot(app.UIAxesSepAngle,app.motion.alpha(1:length(app.motion.fp)),app.motion.fp,"LineWidth",2,'DisplayName','f''')
            plot(app.UIAxesSepAngle,app.motion.alpha(1:length(app.motion.fpp)),app.motion.fpp,"LineWidth",2,'DisplayName','f''''')
            if length(app.UIAxesSepAngle.Children)>1
                legend(app.UIAxesSepAngle,'Location','SouthWest',"FontSize",20)
            else
                legend(app.UIAxesSepAngle,'off')
            end
            title(app.UIAxesSepAngle,app.params);
            
            
        end
        
        function plotSepTime(app)
            % plot seppoint in time
            hold(app.UIAxesSepTime,'off')
            % make sure convective time is already recomputed for this
            % motion
            plot(app.UIAxesSepTime,app.motion.S,interp1(app.airfoil.steady.alpha,app.airfoil.steady.fexp,app.motion.alpha),"LineWidth",2,'DisplayName','f_{exp}')
            hold(app.UIAxesSepTime,'on')
            if ~strcmp(app.ModelDropDown.Value,'BL-Bangga')
                plot(app.UIAxesSepTime,app.motion.S,interp1(app.airfoil.steady.alpha,app.airfoil.steady.f,app.motion.alpha),"LineWidth",2,'DisplayName','f')
            end
            plot(app.UIAxesSepTime,app.motion.S(1:length(app.motion.fp)),app.motion.fp,"LineWidth",2,'DisplayName','f''')
            plot(app.UIAxesSepTime,app.motion.S(1:length(app.motion.fpp)),app.motion.fpp,"LineWidth",2,'DisplayName','f''''')
            if length(app.UIAxesSepTime.Children)>1
                legend(app.UIAxesSepTime,'Location','NorthEast',"FontSize",20)
            else
                legend(app.UIAxesSepTime,'off')
            end
            title(app.UIAxesSepTime,app.params);
        end
        
        function checkfolder(app)
            if ismac
                if strcmp(pwd,'/Users/lucas/Documents/EPFL/PDM/scripts')
                    cd('..')
                elseif ~strcmp(pwd,'/Users/lucas/Documents/EPFL/PDM')
                    error('Move to root folder first')
                end
            elseif ispc
                current_path = split(pwd,'\');
                switch current_path{1}
                    case 'oscar'
                        if strcmp(pwd,'\\oscar\macintosh hd\Users\lucas\Documents\EPFL\PDM\scripts')
                            cd('..')
                        elseif ~strcmp(pwd,'\\oscar\macintosh hd\Users\lucas\Documents\EPFL\PDM')
                            error('Move to root folder first')
                        end
                    case 'D:'
                        if strcmp(pwd,'D:\EPFL\dstoolbox\scripts')
                            cd('..')
                        elseif ~strcmp(pwd,'D:\EPFL\dstoolbox')
                            error('Move to root folder first')
                        end
                end
                
            else
                error('The platform could not be determined.')
            end
            if ~isfolder(fullfile('fig','BLapp'))
                disp('New folder created under fig/BLapp/')
                mkdir(fullfile('fig','BLapp'))
            end
        end
        
        function plotLine(app,ax,xdata,ydata,index)
            plot(ax,xdata(1:length(ydata)),ydata,"LineWidth",2,'SeriesIndex',index,'DisplayName',app.CurveList{index})
            set(ax,'NextPlot','add','ColorOrder',app.cmap);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('src'))
            if strcmp(pwd,fullfile('*','scripts'))
                cd('..')
            end
            switch app.DatasetDropDown.Value
                case 'LB1989'
                    app.CaseDropDown.Enable = false;
                    % Set available models
                    app.ModelDropDown.Items = {'Beddoes-Leishman'};
                    % Set available models
                    app.airfoil = Airfoil('naca0012',0.1);
                    data = load('naca0012.mat');
                    data = data.naca0012;
                    
                    app.motion = PitchingMotion('alpha',data.alpha_xp,'CN',data.CN_xp,'M',0.3,'k',0.1);
                    app.motion.set('freq',app.motion.k*app.motion.V/(pi*app.airfoil.c));
                    app.motion.setName()
                    app.motion.setSinus(app.airfoil,deg2rad(data.mean),deg2rad(data.amp),2*pi/0.032,-pi/2);
                    
                    app.airfoil.steady = SteadyCurve(data.alpha_st,data.CN_st);
                case 'simcos2008'
                    run(fullfile('data','2008_simcos','matlab','labbook_simcos.m'))
                    app.CaseDropDown.Enable = false;
                    % Set available models
                    app.ModelDropDown.Items = {'Beddoes-Leishman','BL-Bangga'};
                    
                    % Static data
                    app.airfoil = Airfoil('OA209',0.3);
                    
                    load('static_corr','mA','mCl_corr')
                    app.airfoil.steady = SteadyCurve(mA,mCl_corr);
                    
                    % Dynamic data
                    nr=13;
                    
                    data = load(pressuredata(nr));
                    
                    % Drag missing, compute the pressure drag
                    CD = zeros(size(data.Cl));
                    for k=1:length(data.Cl)
                        CD(k) = sum(data.Cp(k,:)*data.xk);
                    end
                    
                    load('dynamic_corr','Alpha','Cl_corr')
                    % assuming CL = CN
                    app.motion = PitchingMotion('alpha',Alpha,'CN',Cl_corr.*cosd(Alpha),'k',LB(nr).k,'freq',LB(nr).fosc,'V',50);
                    app.motion.setSinus(app.airfoil,deg2rad(LB(nr).alpha_0),deg2rad(LB(nr).alpha_1),LB(nr).fosc,LB(nr).FS);
                    app.motion.setName('simcos')
                    app.motion.setCNsteady(app.airfoil.steady)
                    
                case 'SH2019'
                    run('labbook.m')
                    % Set available ramps
                    app.CaseDropDown.Enable = true;
                    app.CaseDropDown.Items = {'18','14','22','67','68','69','70','26','27','28','29','84','85','86','87','30','2','34','71','38','6','42','75','46','10','50'};
                    % Set available models
                    app.ModelDropDown.Items = {'Beddoes-Leishman','BL-Bangga','BL-Sheng','BL-Sheng with expfit'};
                    
                    % Airfoil
                    app.airfoil = Airfoil('flatplate',0.15);
                    
                    % Static data
                    static = load('static_flatplate.mat');
                    app.airfoil.steady = SteadyCurve(static.alpha,static.CN,app.AlphaSSField.Value);
                    
                    c = str2double(app.CaseDropDown.Value);
                    load(loadmat(LB(c).ms,LB(c).mpt),'raw','zero');
                    app.motion = RampUpMotion('alpha',raw.alpha,'t',raw.t,'V',LB(c).U,'alphadot',LB(c).alphadot);
                    Cl = raw.Cl - zero.Cl;
                    Cd = raw.Cd - zero.Cd;
                    msname = sprintf('ms%03i',LB(c).ms);
                    app.motion.setName(msname)                                        
                    if strcmp(app.FilterSwitch.Value,'On')
                        fs = 1/app.motion.Ts;
                        Clf = myFilterTwice(Cl,fs);
                        Cdf = myFilterTwice(Cd,fs);
                        app.motion.setCL(Clf);
                        app.motion.setCD(Cdf);
                    elseif strcmp(app.FilterSwitch.Value,'Off')
                        app.motion.setCL(Cl);
                        app.motion.setCD(Cd);
                    else
                        error('The switch has an unexpected value.')
                    end
                    app.motion.isolateRamp(); % cutoff the ramp first for speed
                    app.motion.setPitchRate(app.airfoil)
                    app.motion.computeAirfoilFrame();
                otherwise
                    error('Dataset not defined.')
            end
            % set static slope and zero lift
            app.airfoil.steady.setAlpha0();
            app.airfoil.steady.fitKirchhoff();
            
            app.motion.findExpOnset();
%             % this one needs i_CConset to be defined to work
%             Tvl_estimate = app.motion.estimateTvl();
%             if Tvl_estimate<10 && Tvl_estimate>0
%                 app.Tvl = Tvl_estimate;
%             else
%                 app.Tvl = 2;
%             end
            app.TvlSlider.Value = app.Tvl;
            app.TvlField.Value = app.Tvl;
            app.TotalCheckBox.Value = 1;
            app.ExperimentalCheckBox.Value = 1;
            
            % switch color order (should be done only once !!!)  
            % or alternatively we can load the colormap everytime ...)
            app.cmap = linspecer(12,'qualitative');
            tmp = app.cmap(5,:);
            app.cmap(5,:) = app.cmap(1,:);
            app.cmap(1,:) = tmp;
            tmp = app.cmap(4,:);
            app.cmap(4,:) = app.cmap(2,:);
            app.cmap(2,:) = tmp;
            app.updatePlots()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {660, 660};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {332, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: TpSlider
        function TpSliderValueChanged(app, event)
            app.Tp = app.TpSlider.Value;
            app.TpField.Value = app.Tp;
            if app.EntangledCheckBox.Value
                app.TfSlider.Value = max([0,5-app.Tp]);
                app.TfField.Value = max([0,5 - app.Tp]);
                app.Tf = 5 -app.Tp;
            end
            app.updatePlots()
        end

        % Value changed function: TfSlider
        function TfSliderValueChanged(app, event)
            app.Tf = app.TfSlider.Value;
            app.TfField.Value = app.Tf;
            if app.EntangledCheckBox.Value
                app.TpSlider.Value = 5 - app.Tf;
                app.TpField.Value = 5 - app.Tf;
                app.Tp = 5 - app.Tf;
            end
            app.updatePlots()
        end

        % Value changed function: TvSlider
        function TvSliderValueChanged(app, event)
            app.Tv = app.TvSlider.Value;
            app.TvField.Value = app.Tv;
            app.updatePlots()
        end

        % Value changed function: TvlSlider
        function TvlSliderValueChanged(app, event)
            app.Tvl = app.TvlSlider.Value;
            app.TvlField.Value = app.Tvl;
            app.updatePlots()
        end

        % Value changed function: TpField
        function TpFieldValueChanged(app, event)
            app.Tp = app.TpField.Value;
            if app.Tp <= 10
                app.TpSlider.Value = app.Tp;
            end
            if app.EntangledCheckBox.Value
                app.TfSlider.Value = max([0,5-app.Tp]);
                app.TfField.Value = max([0,5 - app.Tp]);
                app.Tf = 5 -app.Tp;
            end
            app.updatePlots()
        end

        % Value changed function: TfField
        function TfFieldValueChanged(app, event)
            app.Tf = app.TfField.Value;
            if app.Tf <= 10
                app.TfSlider.Value = app.Tf;
            end
            if app.EntangledCheckBox.Value
                app.TpSlider.Value = 5 - app.Tf;
                app.TpField.Value = 5 - app.Tf;
                app.Tp = 5 - app.Tf;
            end
            app.updatePlots()
        end

        % Value changed function: TvField
        function TvFieldValueChanged(app, event)
            app.Tv = app.TvField.Value;
            if app.Tv <= 10
                app.TvSlider.Value = app.Tv;
            end
            app.updatePlots()
        end

        % Value changed function: TvlField
        function TvlFieldValueChanged(app, event)
            app.Tvl = app.TvlField.Value;
            if app.Tvl <= 10
                app.TvlSlider.Value = app.Tvl;
            end
            app.updatePlots()
        end

        % Value changed function: AccVortexCheckBox, 
        % CirculatoryCheckBox, ExperimentalCheckBox, 
        % ImpulsiveCheckBox, LaggedCheckBox, ModKCNICheckBox, 
        % ModKirchoffCheckBox, PotentialCheckBox, 
        % QuasiStaticCheckBox, StaticCheckBox, TotalCheckBox
        function AnyCheckBoxValueChanged(app, event)
            app.updatePlots()
        end

        % Value changed function: AlphaSSField, DatasetDropDown, 
        % FilterSwitch
        function StartUpValueChanged(app, event)
            app.startupFcn()
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            if any(strcmp(app.ModelDropDown.Value,{'Beddoes-Leishman','BL-Bangga'}))
                app.TpSlider.Enable = true;
                app.TpField.Enable = true;
            else
                app.TpSlider.Enable = false;
                app.TpField.Enable = false;
            end
            app.updatePlots()
        end

        % Button pushed function: SaveFigureButtonLoadsAngle
        function SaveFigureButtonLoadsAnglePushed(app, event)
            app.checkfolder()
            exportgraphics(app.UIAxesLoadsAngle,fullfile('fig','BLapp','CN_alpha.png'))
        end

        % Button pushed function: SaveFigureButtonSepAngle
        function SaveFigureButtonSepAnglePushed(app, event)
            app.checkfolder()
            exportgraphics(app.UIAxesSepAngle,fullfile('fig','BLapp','f_curves.png'))
        end

        % Button pushed function: SaveFigureButtonLoadsTime
        function SaveFigureButtonLoadsTimePushed(app, event)
            app.checkfolder()
            exportgraphics(app.UIAxesLoadsTime,fullfile('fig','BLapp','CN_convectime.png'))
        end

        % Button pushed function: SaveFigureButtonSepTime
        function SaveFigureButtonSepTimePushed(app, event)
            app.checkfolder()
            exportgraphics(app.UIAxesSepTime,fullfile('fig','BLapp','f_convectime.png'))
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.updatePlots()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TpSliderLabel = uilabel(app.LeftPanel);
            app.TpSliderLabel.Text = 'Tp';
            app.TpSlider = uislider(app.LeftPanel);
            app.TpSlider.Limits = [0 10];
            app.TpSlider.ValueChangedFcn = createCallbackFcn(app, @TpSliderValueChanged, true);
            app.TpSlider.Value = 5.48;
            app.TfSliderLabel = uilabel(app.LeftPanel);
            app.TfSliderLabel.Text = 'Tf';
            app.TfSlider = uislider(app.LeftPanel);
            app.TfSlider.Limits = [0 10];
            app.TfSlider.ValueChangedFcn = createCallbackFcn(app, @TfSliderValueChanged, true);
            app.TfSlider.Value = 3;
            app.TvSliderLabel = uilabel(app.LeftPanel);
            app.TvSliderLabel.Text = 'Tv';
            app.TvSlider = uislider(app.LeftPanel);
            app.TvSlider.Limits = [0 10];
            app.TvSlider.ValueChangedFcn = createCallbackFcn(app, @TvSliderValueChanged, true);
            app.TvSlider.Value = 1;
            app.CirculatoryCheckBox = uicheckbox(app.LeftPanel);
            app.CirculatoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.CirculatoryCheckBox.Text = 'Circulatory';
            app.ImpulsiveCheckBox = uicheckbox(app.LeftPanel);
            app.ImpulsiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.ImpulsiveCheckBox.Text = 'Impulsive';
            app.PotentialCheckBox = uicheckbox(app.LeftPanel);
            app.PotentialCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.PotentialCheckBox.Text = 'Potential';
            app.AccVortexCheckBox = uicheckbox(app.LeftPanel);
            app.AccVortexCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.AccVortexCheckBox.Text = 'Acc. Vortex';
            app.TotalCheckBox = uicheckbox(app.LeftPanel);
            app.TotalCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.TotalCheckBox.Text = 'Total';
            app.ExperimentalCheckBox = uicheckbox(app.LeftPanel);
            app.ExperimentalCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.ExperimentalCheckBox.Text = 'Experimental';
            app.ModKirchoffCheckBox = uicheckbox(app.LeftPanel);
            app.ModKirchoffCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.ModKirchoffCheckBox.Text = 'Mod. Kirchoff';
            app.LaggedCheckBox = uicheckbox(app.LeftPanel);
            app.LaggedCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.LaggedCheckBox.Text = 'Lagged';
            app.DatasetDropDownLabel = uilabel(app.LeftPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.LeftPanel);
            app.DatasetDropDown.Items = {'LB1989', 'simcos2008', 'SH2019'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @StartUpValueChanged, true);
            app.DatasetDropDown.Value = 'SH2019';
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'Beddoes-Leishman', 'BL-Sheng', 'BL-Sheng with expfit'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Beddoes-Leishman';
            app.FilterexpdataSwitchLabel = uilabel(app.LeftPanel);
            app.FilterexpdataSwitchLabel.Text = 'Filter exp. data';
            app.FilterSwitch = uiswitch(app.LeftPanel, 'slider');
            app.FilterSwitch.ValueChangedFcn = createCallbackFcn(app, @StartUpValueChanged, true);
            app.FilterSwitch.Value = 'On';
            app.TfField = uieditfield(app.LeftPanel, 'numeric');
            app.TfField.Limits = [0 Inf];
            app.TfField.ValueChangedFcn = createCallbackFcn(app, @TfFieldValueChanged, true);
            app.TfField.Value = 3;
            app.TvField = uieditfield(app.LeftPanel, 'numeric');
            app.TvField.Limits = [0 Inf];
            app.TvField.ValueChangedFcn = createCallbackFcn(app, @TvFieldValueChanged, true);
            app.TvField.Value = 1;
            app.TvlSliderLabel = uilabel(app.LeftPanel);
            app.TvlSliderLabel.Text = 'Tvl';
            app.TvlSlider = uislider(app.LeftPanel);
            app.TvlSlider.Limits = [0 10];
            app.TvlSlider.ValueChangedFcn = createCallbackFcn(app, @TvlSliderValueChanged, true);
            app.TvlSlider.Value = 1.7;
            app.TpField = uieditfield(app.LeftPanel, 'numeric');
            app.TpField.Limits = [0 Inf];
            app.TpField.ValueChangedFcn = createCallbackFcn(app, @TpFieldValueChanged, true);
            app.TpField.Value = 5.48;
            app.TvlField = uieditfield(app.LeftPanel, 'numeric');
            app.TvlField.Limits = [0 Inf];
            app.TvlField.ValueChangedFcn = createCallbackFcn(app, @TvlFieldValueChanged, true);
            app.TvlField.Value = 1.7;
            app.cDropDownLabel = uilabel(app.LeftPanel);
            app.cDropDownLabel.Text = 'c';
            app.CaseDropDown = uidropdown(app.LeftPanel);
            app.CaseDropDown.Items = {'22', '26', '30', '34', '71'};
            app.CaseDropDown.Value = '71';
            app.StaticCheckBox = uicheckbox(app.LeftPanel);
            app.StaticCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.StaticCheckBox.Text = 'Static';
            app.QuasiStaticCheckBox = uicheckbox(app.LeftPanel);
            app.QuasiStaticCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.QuasiStaticCheckBox.Text = 'Quasi-static';
            app.ModKCNICheckBox = uicheckbox(app.LeftPanel);
            app.ModKCNICheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyCheckBoxValueChanged, true);
            app.ModKCNICheckBox.Text = 'Mod. K + CNI';
            app.StaticstallangleLabel = uilabel(app.LeftPanel);
            app.StaticstallangleLabel.Text = 'Static stall angle (°)';
            app.AlphaSSField = uieditfield(app.LeftPanel, 'numeric');
            app.AlphaSSField.Limits = [0 20];
            app.AlphaSSField.ValueChangedFcn = createCallbackFcn(app, @StartUpValueChanged, true);
            app.AlphaSSField.Value = 13;
            app.EntangledCheckBox = uicheckbox(app.LeftPanel);
            app.EntangledCheckBox.Text = 'Entangled';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoadsAngleTab = uitab(app.TabGroup);
            app.LoadsAngleTab.Title = 'loads in angle';
            app.UIAxesLoadsAngle = uiaxes(app.LoadsAngleTab);
            title(app.UIAxesLoadsAngle, 'Normal coefficients')
            xlabel(app.UIAxesLoadsAngle, '\alpha (°)')
            ylabel(app.UIAxesLoadsAngle, 'C_N')
            app.UIAxesLoadsAngle.XLim = [0 20];
            app.UIAxesLoadsAngle.YLim = [-0.5 2.2];
            app.SaveFigureButtonLoadsAngle = uibutton(app.LoadsAngleTab, 'push');
            app.SaveFigureButtonLoadsAngle.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonLoadsAnglePushed, true);
            app.SaveFigureButtonLoadsAngle.Text = 'Save Figure';
            app.LoadsTimeTab = uitab(app.TabGroup);
            app.LoadsTimeTab.Title = 'loads in time';
            app.UIAxesLoadsTime = uiaxes(app.LoadsTimeTab);
            title(app.UIAxesLoadsTime, 'Normal coefficients')
            xlabel(app.UIAxesLoadsTime, 't_c')
            ylabel(app.UIAxesLoadsTime, 'C_N')
            app.UIAxesLoadsTime.XLim = [0 20];
            app.UIAxesLoadsTime.YLim = [-0.5 2.2];
            app.SaveFigureButtonLoadsTime = uibutton(app.LoadsTimeTab, 'push');
            app.SaveFigureButtonLoadsTime.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonLoadsTimePushed, true);
            app.SaveFigureButtonLoadsTime.Text = 'Save Figure';
            app.SepAngleTab = uitab(app.TabGroup);
            app.SepAngleTab.Title = 'seppoint in angle';
            app.UIAxesSepAngle = uiaxes(app.SepAngleTab);
            title(app.UIAxesSepAngle, 'Separation point')
            xlabel(app.UIAxesSepAngle, '\alpha (°)')
            ylabel(app.UIAxesSepAngle, 'f')
            app.SaveFigureButtonSepAngle = uibutton(app.SepAngleTab, 'push');
            app.SaveFigureButtonSepAngle.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonSepAnglePushed, true);
            app.SaveFigureButtonSepAngle.Text = 'Save Figure';
            app.SepTimeTab = uitab(app.TabGroup);
            app.SepTimeTab.Title = 'seppoint in time';
            app.UIAxesSepTime = uiaxes(app.SepTimeTab);
            title(app.UIAxesSepTime, 'Separation point')
            xlabel(app.UIAxesSepTime, 't_c')
            ylabel(app.UIAxesSepTime, 'f')
            app.SaveFigureButtonSepTime = uibutton(app.SepTimeTab, 'push');
            app.SaveFigureButtonSepTime.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonSepTimePushed, true);
            app.SaveFigureButtonSepTime.Text = 'Save Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BLapp2020

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end